classdef holodoppler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HoloDopplerUIFigure            matlab.ui.Figure
        GridLayout4                    matlab.ui.container.GridLayout
        ImageRight                     matlab.ui.control.Image
        ImageLeft                      matlab.ui.control.Image
        CurrentFilePanel               matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        RenderingparametersPanel       matlab.ui.container.Panel
        RenderPreviewLamp              matlab.ui.control.Lamp
        SavePreviewButton              matlab.ui.control.Button
        RenderPreviewButton            matlab.ui.control.Button
        flat_field_gw                  matlab.ui.control.NumericEditField
        flat_field_gwEditFieldLabel    matlab.ui.control.Label
        time_range2                    matlab.ui.control.NumericEditField
        time_range1                    matlab.ui.control.NumericEditField
        time_rangeLabel                matlab.ui.control.Label
        time_transform                 matlab.ui.control.DropDown
        time_transformDropDownLabel    matlab.ui.control.Label
        svd_threshold                  matlab.ui.control.NumericEditField
        svdx_filter                    matlab.ui.control.CheckBox
        svd_filter                     matlab.ui.control.CheckBox
        spatial_propagation            matlab.ui.control.NumericEditField
        spatial_propagationEditFieldLabel  matlab.ui.control.Label
        spatial_transformation         matlab.ui.control.DropDown
        spatial_transformationDropDownLabel  matlab.ui.control.Label
        spatial_filter_range2          matlab.ui.control.NumericEditField
        spatial_filter_range1          matlab.ui.control.NumericEditField
        spatial_filter_range           matlab.ui.control.Label
        hilbert_filter                 matlab.ui.control.CheckBox
        spatial_filter                 matlab.ui.control.CheckBox
        svd_threshold_reset_button     matlab.ui.control.Button
        AberrationcompensationPanel    matlab.ui.container.Panel
        imagesubapsizeratioEditField   matlab.ui.control.NumericEditField
        imagesubapsizeratioEditFieldLabel  matlab.ui.control.Label
        subapnumpositionsEditField     matlab.ui.control.NumericEditField
        subapnumpositionsEditFieldLabel  matlab.ui.control.Label
        referenceimageDropDown         matlab.ui.control.DropDown
        referenceimageDropDownLabel    matlab.ui.control.Label
        ZernikeProjectionCheckBox      matlab.ui.control.CheckBox
        savecoefsCheckBox              matlab.ui.control.CheckBox
        rangeYEditField                matlab.ui.control.NumericEditField
        rangeYEditFieldLabel           matlab.ui.control.Label
        rangeZEditField                matlab.ui.control.NumericEditField
        rangeZEditFieldLabel           matlab.ui.control.Label
        volumesizeEditField            matlab.ui.control.NumericEditField
        volumesizeEditFieldLabel       matlab.ui.control.Label
        maxSubAp_PCAEditField          matlab.ui.control.NumericEditField
        maxEditField_2Label            matlab.ui.control.Label
        minSubAp_PCAEditField          matlab.ui.control.NumericEditField
        minEditField_2Label            matlab.ui.control.Label
        SubAp_PCACheckBox              matlab.ui.control.CheckBox
        PreviewLabel                   matlab.ui.control.Label
        masknumiterEditField           matlab.ui.control.NumericEditField
        masknumiterEditFieldLabel      matlab.ui.control.Label
        maxconstraintEditField         matlab.ui.control.NumericEditField
        maxconstraintEditFieldLabel    matlab.ui.control.Label
        zernikestolEditField           matlab.ui.control.NumericEditField
        toleranceLabel                 matlab.ui.control.Label
        optimizationzernikeranksEditField  matlab.ui.control.NumericEditField
        zernikeranksEditField_2Label   matlab.ui.control.Label
        IterativeoptimizationCheckBox  matlab.ui.control.CheckBox
        subaperturemarginEditField     matlab.ui.control.NumericEditField
        subaperturemarginEditFieldLabel  matlab.ui.control.Label
        shackhartmannzernikeranksEditField  matlab.ui.control.NumericEditField
        zernikeranksEditFieldLabel     matlab.ui.control.Label
        ShackHartmannCheckBox          matlab.ui.control.CheckBox
        Label_2                        matlab.ui.control.Label
        UIAxes_aberrationPreview       matlab.ui.control.UIAxes
        AdvancedProcessingPanel        matlab.ui.container.Panel
        SVDTresholdEditFieldLabel_2    matlab.ui.control.Label
        SVDStrideEditField             matlab.ui.control.NumericEditField
        SVDThresholdCheckBox           matlab.ui.control.CheckBox
        SVDTresholdEditFieldLabel      matlab.ui.control.Label
        SVDThresholdEditField          matlab.ui.control.NumericEditField
        SVDxCheckBox                   matlab.ui.control.CheckBox
        SVDx_SubApEditField            matlab.ui.control.NumericEditField
        SVDx_SubApEditFieldLabel       matlab.ui.control.Label
        xystrideEditField              matlab.ui.control.NumericEditField
        xystrideEditFieldLabel         matlab.ui.control.Label
        r1EditField                    matlab.ui.control.NumericEditField
        r1EditFieldLabel               matlab.ui.control.Label
        unitcellsinlatticeEditField    matlab.ui.control.NumericEditField
        unitcellsinlatticeEditFieldLabel  matlab.ui.control.Label
        nu2EditField                   matlab.ui.control.NumericEditField
        nu2EditFieldLabel              matlab.ui.control.Label
        nu1EditField                   matlab.ui.control.NumericEditField
        nu1EditFieldLabel              matlab.ui.control.Label
        phi2EditField                  matlab.ui.control.NumericEditField
        phi2EditFieldLabel             matlab.ui.control.Label
        phi1EditField                  matlab.ui.control.NumericEditField
        phi1EditFieldLabel             matlab.ui.control.Label
        LocalfilteringLabel            matlab.ui.control.Label
        temporalCheckBox               matlab.ui.control.CheckBox
        spatialCheckBox                matlab.ui.control.CheckBox
        SVDCheckBox                    matlab.ui.control.CheckBox
        FileselectionPanel             matlab.ui.container.Panel
        RefreshAppButton               matlab.ui.control.Button
        num_frames                     matlab.ui.control.Label
        file_loaded_lamp               matlab.ui.control.Lamp
        positioninfileSlider           matlab.ui.control.Slider
        positioninfileSliderLabel      matlab.ui.control.Label
        frame_position                 matlab.ui.control.NumericEditField
        ppx                            matlab.ui.control.NumericEditField
        ppxy_label                     matlab.ui.control.Label
        ppy                            matlab.ui.control.NumericEditField
        setUpDropDown                  matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        parfor_arg                     matlab.ui.control.Spinner
        numworkersSpinnerLabel         matlab.ui.control.Label
        NotesTextArea                  matlab.ui.control.TextArea
        NotesTextAreaLabel             matlab.ui.control.Label
        fs                             matlab.ui.control.NumericEditField
        fsEditFieldLabel               matlab.ui.control.Label
        ParallelismDropDown            matlab.ui.control.DropDown
        lambda                         matlab.ui.control.NumericEditField
        wavelengthEditFieldLabel       matlab.ui.control.Label
        LoadfileButton                 matlab.ui.control.Button
        BatchselectionparametersandVideoRenderingPanel  matlab.ui.container.Panel
        PlayButton                     matlab.ui.control.Button
        ShowHistogramButton            matlab.ui.control.Button
        VideoRenderingLamp             matlab.ui.control.Lamp
        SaveVideoButton                matlab.ui.control.Button
        batch_size                     matlab.ui.control.NumericEditField
        batchsizeLabel                 matlab.ui.control.Label
        Image_typesListBox             matlab.ui.control.ListBox
        Image_typesListBoxLabel        matlab.ui.control.Label
        DxEditFieldLabel_2             matlab.ui.control.Label
        registration_disc_ratio        matlab.ui.control.NumericEditField
        batch_size_registration        matlab.ui.control.NumericEditField
        refbatchsizeEditFieldLabel     matlab.ui.control.Label
        batch_stride                   matlab.ui.control.NumericEditField
        batchstrideEditFieldLabel      matlab.ui.control.Label
        image_registration             matlab.ui.control.CheckBox
        SaveconfigButton               matlab.ui.control.Button
        showrefCheckBox                matlab.ui.control.CheckBox
        iterativeregistrationCheckBox  matlab.ui.control.CheckBox
        saveconfigLamp                 matlab.ui.control.Lamp
        ApplytoallfilesCheckBox        matlab.ui.control.CheckBox
        VideoRenderingButton           matlab.ui.control.Button
        FoldermanagementButton         matlab.ui.control.Button
        temporalfilterEditField        matlab.ui.control.NumericEditField
        temporalfilterCheckBox         matlab.ui.control.CheckBox
        phaseregistrationCheckBox      matlab.ui.control.CheckBox
        rephasingCheckBox              matlab.ui.control.CheckBox
        RightClickImageContextMenu     matlab.ui.container.ContextMenu
        NextMenu                       matlab.ui.container.Menu
        ViewAllMenu                    matlab.ui.container.Menu
    end


    properties (Access = public)
        file_loaded
        HD %HoloDopplerClass
        drawer_list = {}
    end

    properties (Access = private)
        DialogApp % Dialog box app
        poolobj
    end
    
    methods (Access = private)
           
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if exist("version.txt",'file')
                v = readlines('version.txt');
                fprintf("==========================================\n " + ...
                    "Welcome to HoloDoppler %s\n" + ...
                    "------------------------------------------\n" + ...
                    "Developed by the DigitalHolographyFoundation\n" + ...
                    "==========================================\n",v(1));
            end
            % add subfolders to path
            
            addpath('New Folder\',"ReaderClasses\"); % dossier à ajouter au research path matlab pour utiliser correctement l'application

            app.HD = HoloDopplerClass();
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            %% open file and manage file extension
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            if (isempty(app.HD.file))
                [fname, fpath] = uigetfile('*.raw;*.cine;*.holo');
            else
                [fname, fpath] = uigetfile(strcat(app.HD.file.path, "*.raw;*.cine;*.holo"));
            end
            delete(f); %delete the dummy figure
            if fname == 0
                return
            end
            try 
                app.HD.LoadFile(fullfile(fpath, fname));
                classtogui(app.HD,app);
                app.RenderPreviewButtonPushed();
                app.file_loaded = 1; app.file_loaded_lamp.Color = [0 1 0]; %
            catch ME
                % Display error message and line number
                fprintf('Error: %s\n', ME.message);
                fprintf('Occurred in: %s at line %d\n', ME.stack(1).name, ME.stack(1).line);
                app.file_loaded_lamp.Color = [0.8 0.1 0.1]; 
                drawnow
            end
            
            if ~isempty(app.HD.file)
                app.CurrentFilePanel.Title = ['Current File : ' app.HD.file.path];
            end
        end

        % Callback function
        function spatial_propagationValueChanged(app, event)

        end

        % Callback function
        function f1EditFieldValueChanged(app, event)

        end

        % Callback function
        function f2EditFieldValueChanged(app, event)

        end

        % Callback function
        function wavelengthEditFieldValueChanged(app, event)

        end

        % Callback function
        function batchsizeEditFieldValueChanged(app, event)

        end

        % Button pushed function: VideoRenderingButton
        function VideoRenderingButtonPushed(app, event)
            app.VideoRenderingLamp.Color = [0.93 0.69 0.13];drawnow;  % orange in process
            try 
                app.HD.VideoRendering();
                app.VideoRenderingLamp.Color = [0 1 0]; % green success
            catch ME
                % Display error message and line number
                fprintf('Error: %s\n', ME.message);
                fprintf('Occurred in: %s at line %d\n', ME.stack(1).name, ME.stack(1).line);
                for stackIdx = 1:size(ME.stack,1)
                        fprintf('%s : %s, line : %d \n',ME.stack(stackIdx).file, ME.stack(stackIdx).name, ME.stack(stackIdx).line);
                end
                app.VideoRenderingLamp.Color = [0.8 0.1 0.1]; % error happened
            end
            
        end

        % Callback function
        function AutofocusButtonPushed(app, event)

        end

        % Close request function: HoloDopplerUIFigure
        function HoloDopplerUIFigureCloseRequest(app, event)
            delete(app.HoloDopplerUIFigure);
        end

        % Callback function
        function positioninfileSliderValueChanged(app, event)
         
        end

        % Callback function
        function compositef1EditFieldValueChanged(app, event)
            
        end

        % Callback function
        function compositef2EditFieldValueChanged(app, event)
            
        end

        % Callback function
        function compositef3EditFieldValueChanged(app, event)
            
        end

        % Callback function
        function showSpatialFilterCheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function frame_positionValueChanged(app, event)

        end

        % Value changed function: rephasingCheckBox
        function rephasingCheckBoxValueChanged(app, event)

        end

        % Callback function
        function ImageChoiceDropDownValueChanged(app, event)

        end

        % Callback function
        function blurEditFieldValueChanged(app, event)
           
        end

        % Callback function
        function spatialTransformationChanged(app, event)

        end

        % Callback function
        function setUpChanged(app, event)

        end

        % Button pushed function: SaveconfigButton
        function SaveconfigButtonPushed(app, event)
            if app.ApplytoallfilesCheckBox
                for i = 1:length(app.HD.drawer_list)
                    app.HD.saveParams(app.HD.drawer_list{i});
                end
            else
                app.HD.saveParams();
            end
        end

        % Callback function
        function PCACheckBoxValueChanged(app, event)

        end

        % Value changed function: SubAp_PCACheckBox
        function SubAp_PCACheckBoxValueChanged(app, event)

        end

        % Value changed function: minSubAp_PCAEditField
        function minSubApPCAEditField_2ValueChanged(app, event)

        end

        % Value changed function: maxSubAp_PCAEditField
        function maxSubAp_PCAEditField_2_2ValueChanged(app, event)

        end

        % Callback function
        function min_PCAEditFieldValueChanged(app, event)

        end

        % Callback function
        function max_PCAEditFieldValueChanged(app, event)

        end

        % Callback function
        function TimeTransform(app, event)

        end

        % Callback function
        function ZSwitchValueChanged(app, event)

        end

        % Callback function
        function zirisEditFieldValueChanged(app, event)

        end

        % Callback function
        function outputVideo(app, event)

        end

        % Callback function
        function RenderpreviewButtonPushed(app, event)

        end

        % Window key press function: HoloDopplerUIFigure
        function HoloDopplerUIFigureWindowKeyPress(app, event)
            switch event.Key
                case 'return'
                    app.RenderPreviewButtonPushed();
                case 'rightarrow'
                    app.NextMenuSelected();
            end
        end

        % Button pushed function: FoldermanagementButton
        function FoldermanagementButtonPushed(app, event)
            d = dialog('Position', [300, 300, 750, 90 + length(app.HD.drawer_list) * 14],...
                'Color', [0.2, 0.2, 0.2],...
                'Name', 'Folder management',...
                'Resize', 'on',...
                'WindowStyle', 'normal');

            txt = uicontrol('Parent', d,...
                'Style', 'text',...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.2, 0.2, 0.2],...
                'ForegroundColor', [0.8, 0.8, 0.8],...
                'Position', [20, 70, 710, length(app.HD.drawer_list) * 14],...
                'HorizontalAlignment', 'left',...
                'String', app.HD.drawer_list);

            uicontrol('Parent', d,...
                'Position', [20, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Select file',...
                'Callback', @select);

            uicontrol('Parent', d,...
                'Position', [140, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Select folder',...
                'Callback', @select_all);

            uicontrol('Parent', d,...
                'Position', [260, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Clear list',...
                'Callback', @clear_drawer);

            uicontrol('Parent', d,...
                'Position', [380, 20, 100, 25],...
                'FontName', 'Helvetica',...
                'BackgroundColor', [0.5, 0.5, 0.5],...
                'ForegroundColor', [0.9 0.9 0.9],...
                'FontWeight', 'bold',...
                'String', 'Render',...
                'Callback', @render);

            uiwait(d);

            function select(~, ~)
                [selected_file,path] = uigetfile();
                if (selected_file)
                    [~, ~, ext] = fileparts(selected_file);
                    if ismember(ext, {'.cine', '.holo'})
                        app.HD.drawer_list{end + 1} = fullfile(path,selected_file);
                    else 
                        fprintf("File should be of extension .cine or .holo")
                    end
                end
                txt.String = app.HD.drawer_list;
                d.Position(4) = 100 + length(app.HD.drawer_list) * 14;
                txt.Position(4) = length(app.HD.drawer_list) * 14;
            end

            function select_all(~, ~)
                selected_folder = uigetdir();
                if selected_folder
                    % List of files and folders within the selected directory
                    tmp_dir = dir(selected_folder);
                    % Loop through the files and folders in the selected directory
                    for i = 1:numel(tmp_dir)
                        % Skip directories (.) and (..) and process files only
                        if ~tmp_dir(i).isdir
                            [~, ~, ext] = fileparts(tmp_dir(i).name); % Get the file extension
                            % Check if the file has a .cine or .holo extension
                            if ismember(ext, {'.cine', '.holo'})
                                % Add file to the drawer list
                                app.HD.drawer_list{end + 1} = fullfile(selected_folder, tmp_dir(i).name);
                            end
                        end
                    end
                    % Update the display
                    txt.String = app.HD.drawer_list;
                    d.Position(4) = 100 + length(app.HD.drawer_list) * 14;
                    txt.Position(4) = length(app.HD.drawer_list) * 14;
                end
            end

            function clear_drawer(~, ~)
                app.HD.drawer_list = {};
                txt.String = app.HD.drawer_list;
                d.Position(4) = 100 + length(app.HD.drawer_list) * 14;
                txt.Position(4) = length(app.HD.drawer_list) * 14;
            end

            function render(~, ~)
                file_list = {};
                for i = 1:length(app.HD.drawer_list) % Circles through the files added by the user
                    % Get config files
                    config_list = get_config_files(app.HD.drawer_list{i});
                    
                    if (~isempty(config_list))
                        file_list{end + 1} = {app.HD.drawer_list{i}, config_list}; 
                    end
                end

                for i = 1:length(file_list)
                    if (~isempty(file_list{i}{2}))
                        for j = 1:length(file_list{i}{2})
                            app.HD.Loadfile(file_list{i}{1}, file_list{i}{2}{j});
                            app.HD.VideoRendering();
                            app.HD.saveVideo();
                        end
                    end
                end
            end
            delete(d);
        end

        % Callback function
        function SavePreview(app, event)
            %Savepreview(app);
        end

        % Value changed function: SVDThresholdCheckBox
        function SVDThresholdCheckBoxValueChanged(app, event)
            
        end

        % Value changed function: rangeZEditField
        function rangeZEditFieldValueChanged(app, event)
         
        end

        % Value changed function: rangeYEditField
        function rangeYEditFieldValueChanged(app, event)
           
        end

        % Image clicked function: ImageLeft
        function ImageLeftClicked(app, event)
            
        end

        % Image clicked function: ImageRight
        function ImageRightClicked(app, event)
            
        end

        % Callback function
        function image_registrationValueChanged(app, event)
            
        end

        % Callback function
        function ShowSpectrumButtonPushed(app, event)
            
        end

        % Value changed function: SVDThresholdEditField
        function SVDThresholdEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function scan3DButtonPushed(app, event)
            
        end

        % Callback function
        function registrationdiscCheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function spatialfilterratioValueChanged(app, event)
            
        end

        % Callback function
        function ppxValueChanged(app, event)

        end

        % Callback function
        function fsValueChanged(app, event)
         
        end

        % Value changed function: Image_typesListBox, batch_size, 
        % ...and 23 other components
        function refreshClass(app, event)
            guitoclass(app.HD,app);
        end

        % Callback function
        function re(app, event)
            
        end

        % Button pushed function: RenderPreviewButton
        function RenderPreviewButtonPushed(app, event)
            app.RenderPreviewLamp.Color = [0.93 0.69 0.13];drawnow;  % orange in process
            
            try 
                Images = app.HD.PreviewRendering();
                app.RenderPreviewLamp.Color = [0 1 0]; % green success
            catch ME
                Images = [];
                % Display error message and line number
                fprintf('Error: %s\n', ME.message);
                fprintf('Occurred in: %s at line %d\n', ME.stack(1).name, ME.stack(1).line);
                for stackIdx = 1:size(ME.stack,1)
                        fprintf('%s : %s, line : %d \n',ME.stack(stackIdx).file, ME.stack(stackIdx).name, ME.stack(stackIdx).line);
                end
                app.RenderPreviewLamp.Color = [0.8 0.1 0.1];% error happened
                drawnow
            end
            
            
            if ~isempty(Images)
                app.ImageLeft.ImageSource = toImageSource(Images{1});
            else
                app.ImageLeft.ImageSource = '';
            end

            
        end

        % Menu selected function: NextMenu
        function NextMenuSelected(app, event)
            imgs = app.HD.view.getImages(app.HD.params.image_types);
            if ~isempty(imgs)
                app.ImageLeft.ImageSource = toImageSource(imgs{randsample(numel(imgs),1)});
            else
                app.ImageLeft.ImageSource = '';
            end
        end

        % Menu selected function: ViewAllMenu
        function ViewAllMenuSelected(app, event)
            app.HD.showPreviewImages();
        end

        % Button pushed function: SaveVideoButton
        function SaveVideoButtonPushed(app, event)
            app.HD.SaveVideo();
        end

        % Button pushed function: SavePreviewButton
        function SavePreviewButtonPushed(app, event)
            app.HD.savePreview();
        end

        % Button pushed function: svd_threshold_reset_button
        function svd_threshold_reset_buttonPushed(app, event)
            if app.svd_threshold.Value == 0
                val = ceil(app.time_range1.Value * 2 * app.batch_size.Value / app.fs.Value);
                if ~isnan(val)
                    app.svd_threshold.Value = val;
                end
            else 
                app.svd_threshold.Value = 0;
            end
        end

        % Button pushed function: ShowHistogramButton
        function ShowHistogramButtonPushed(app, event)
            app.HD.view.showFramesHistogram();
        end

        % Value changed function: time_range2
        function time_range2ValueChanged(app, event)
            
            if strcmp(app.time_transform,'FFT') && app.time_range2.Value > app.fs.Value/2
                app.time_range2.Value = app.fs.Value/2;
            end
            app.refreshClass();
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.HD.showVideo();
        end

        % Button pushed function: RefreshAppButton
        function RefreshAppButtonPushed(app, event)
            classtogui(app.HD,app);
            if isempty(app.HD.params.image_types)
                return
            end
            imgs = app.HD.view.getImages({app.HD.params.image_types{1}});
            if ~isempty(imgs)
                app.ImageLeft.ImageSource = toImageSource(imgs{randsample(numel(imgs),1)});
            else
                app.ImageLeft.ImageSource = '';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HoloDopplerUIFigure = uifigure('Visible', 'off');
            app.HoloDopplerUIFigure.Name = 'HoloDoppler';
            app.HoloDopplerUIFigure.Icon = fullfile(pathToMLAPP, 'holowaves_logo_temp.png');
            app.HoloDopplerUIFigure.CloseRequestFcn = createCallbackFcn(app, @HoloDopplerUIFigureCloseRequest, true);
            app.HoloDopplerUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @HoloDopplerUIFigureWindowKeyPress, true);
            app.GridLayout4 = uigridlayout(app.HoloDopplerUIFigure);
            app.FileselectionPanel = uipanel(app.GridLayout4);
            app.FileselectionPanel.Tooltip = {''};
            app.FileselectionPanel.Title = 'File selection';
            app.BatchselectionparametersandVideoRenderingPanel = uipanel(app.FileselectionPanel);
            app.BatchselectionparametersandVideoRenderingPanel.Title = 'Batch selection parameters and Video Rendering';
            app.rephasingCheckBox = uicheckbox(app.BatchselectionparametersandVideoRenderingPanel);
            app.rephasingCheckBox.ValueChangedFcn = createCallbackFcn(app, @rephasingCheckBoxValueChanged, true);
            app.rephasingCheckBox.Visible = 'off';
            app.rephasingCheckBox.Text = 'rephasing';
            app.rephasingCheckBox.Value = true;
            app.phaseregistrationCheckBox = uicheckbox(app.BatchselectionparametersandVideoRenderingPanel);
            app.phaseregistrationCheckBox.Visible = 'off';
            app.phaseregistrationCheckBox.Tooltip = {'Activates video registration. Check this if the video is too shaky.'};
            app.phaseregistrationCheckBox.Text = 'phase registration';
            app.temporalfilterCheckBox = uicheckbox(app.BatchselectionparametersandVideoRenderingPanel);
            app.temporalfilterCheckBox.Visible = 'off';
            app.temporalfilterCheckBox.Text = 'temporal filter';
            app.temporalfilterEditField = uieditfield(app.BatchselectionparametersandVideoRenderingPanel, 'numeric');
            app.temporalfilterEditField.Limits = [1 Inf];
            app.temporalfilterEditField.Visible = 'off';
            app.temporalfilterEditField.Tooltip = {'Laser wavelength in nanometers'};
            app.temporalfilterEditField.Value = 2;
            app.FoldermanagementButton = uibutton(app.BatchselectionparametersandVideoRenderingPanel, 'push');
            app.FoldermanagementButton.ButtonPushedFcn = createCallbackFcn(app, @FoldermanagementButtonPushed, true);
            app.FoldermanagementButton.Tooltip = {'Open a window to render all files from different folders with their config files'};
            app.FoldermanagementButton.Text = 'Folder management';
            app.VideoRenderingButton = uibutton(app.BatchselectionparametersandVideoRenderingPanel, 'push');
            app.VideoRenderingButton.ButtonPushedFcn = createCallbackFcn(app, @VideoRenderingButtonPushed, true);
            app.VideoRenderingButton.Tooltip = {'Render ''batch_size'' frame batchs spaced by ''batch_stride'' and output a video of different image types'};
            app.VideoRenderingButton.Text = 'Video Rendering';
            app.ApplytoallfilesCheckBox = uicheckbox(app.BatchselectionparametersandVideoRenderingPanel);
            app.ApplytoallfilesCheckBox.Tooltip = {'Save a configuration for the video rendering of a file'};
            app.ApplytoallfilesCheckBox.Text = 'Apply to all files';
            app.saveconfigLamp = uilamp(app.BatchselectionparametersandVideoRenderingPanel);
            app.saveconfigLamp.Tooltip = {'Save a configuration for the video rendering of a file'};
            app.iterativeregistrationCheckBox = uicheckbox(app.BatchselectionparametersandVideoRenderingPanel);
            app.iterativeregistrationCheckBox.Visible = 'off';
            app.iterativeregistrationCheckBox.Tooltip = {'''Activates video registration. Check this if the video is too shaky.'''};
            app.iterativeregistrationCheckBox.Text = 'iterative registration';
            app.showrefCheckBox = uicheckbox(app.BatchselectionparametersandVideoRenderingPanel);
            app.showrefCheckBox.Visible = 'off';
            app.showrefCheckBox.Text = 'show ref';
            app.SaveconfigButton = uibutton(app.BatchselectionparametersandVideoRenderingPanel, 'push');
            app.SaveconfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveconfigButtonPushed, true);
            app.SaveconfigButton.Tooltip = {'Save a configuration for the video rendering of a file'};
            app.SaveconfigButton.Text = 'Save config';
            app.image_registration = uicheckbox(app.BatchselectionparametersandVideoRenderingPanel);
            app.image_registration.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.image_registration.Tooltip = {'Activate frame to frame translation registration of images'};
            app.image_registration.Text = 'image registration';
            app.batchstrideEditFieldLabel = uilabel(app.BatchselectionparametersandVideoRenderingPanel);
            app.batchstrideEditFieldLabel.Text = 'batch stride';
            app.batch_stride = uieditfield(app.BatchselectionparametersandVideoRenderingPanel, 'numeric');
            app.batch_stride.Limits = [0 Inf];
            app.batch_stride.ValueDisplayFormat = '%.0f';
            app.batch_stride.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.batch_stride.Tooltip = {'Number of interferograms to skip between two images'};
            app.refbatchsizeEditFieldLabel = uilabel(app.BatchselectionparametersandVideoRenderingPanel);
            app.refbatchsizeEditFieldLabel.Text = 'reg batch size';
            app.batch_size_registration = uieditfield(app.BatchselectionparametersandVideoRenderingPanel, 'numeric');
            app.batch_size_registration.Limits = [0 Inf];
            app.batch_size_registration.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.batch_size_registration.Tooltip = {'Number of interferograms usedcompute the image used as reference in registration'};
            app.registration_disc_ratio = uieditfield(app.BatchselectionparametersandVideoRenderingPanel, 'numeric');
            app.registration_disc_ratio.Limits = [0 1000];
            app.registration_disc_ratio.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.registration_disc_ratio.Tooltip = {'Size of a disk centered on the images used to compute the registration shifts (0 is empty and 1 is a disc of the maximum dimension).'};
            app.DxEditFieldLabel_2 = uilabel(app.BatchselectionparametersandVideoRenderingPanel);
            app.DxEditFieldLabel_2.Text = 'disk';
            app.Image_typesListBoxLabel = uilabel(app.BatchselectionparametersandVideoRenderingPanel);
            app.Image_typesListBoxLabel.Text = 'Image_types';
            app.Image_typesListBox = uilistbox(app.BatchselectionparametersandVideoRenderingPanel);
            app.Image_typesListBox.Items = {};
            app.Image_typesListBox.Multiselect = 'on';
            app.Image_typesListBox.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.Image_typesListBox.Tooltip = {'Image types output giving extracting different informations from a batch of frames'};
            app.Image_typesListBox.Value = {};
            app.batchsizeLabel = uilabel(app.BatchselectionparametersandVideoRenderingPanel);
            app.batchsizeLabel.Text = 'batch size';
            app.batch_size = uieditfield(app.BatchselectionparametersandVideoRenderingPanel, 'numeric');
            app.batch_size.Limits = [0 Inf];
            app.batch_size.ValueDisplayFormat = '%.0f';
            app.batch_size.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.batch_size.Tooltip = {'Number of interferograms used to compute the image'};
            app.SaveVideoButton = uibutton(app.BatchselectionparametersandVideoRenderingPanel, 'push');
            app.SaveVideoButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVideoButtonPushed, true);
            app.SaveVideoButton.Tooltip = {'Render ''batch_size'' frame batchs spaced by ''batch_stride'' and output a video of different image types'};
            app.SaveVideoButton.Text = 'Save Video';
            app.VideoRenderingLamp = uilamp(app.BatchselectionparametersandVideoRenderingPanel);
            app.ShowHistogramButton = uibutton(app.BatchselectionparametersandVideoRenderingPanel, 'push');
            app.ShowHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogramButtonPushed, true);
            app.ShowHistogramButton.Tooltip = {'Show the full frame batch histogram'};
            app.ShowHistogramButton.Text = 'Histogram';
            app.PlayButton = uibutton(app.BatchselectionparametersandVideoRenderingPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Tooltip = {'Play the video'};
            app.PlayButton.Text = 'l>';
            app.LoadfileButton = uibutton(app.FileselectionPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.wavelengthEditFieldLabel = uilabel(app.FileselectionPanel);
            app.wavelengthEditFieldLabel.Tooltip = {'Laser''s wavelength for light propagation calculations in (m)'};
            app.wavelengthEditFieldLabel.Text = 'wavelength';
            app.lambda = uieditfield(app.FileselectionPanel, 'numeric');
            app.lambda.Limits = [0 Inf];
            app.lambda.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.lambda.Tooltip = {'Laser wavelength in nanometers'};
            app.ParallelismDropDown = uidropdown(app.FileselectionPanel);
            app.ParallelismDropDown.Items = {'CPU multithread', 'LightGPU', 'GPU parallelization', 'CPU singlethread', 'CPU/GPU rendering/optim'};
            app.ParallelismDropDown.Enable = 'off';
            app.ParallelismDropDown.Visible = 'off';
            app.ParallelismDropDown.Value = 'CPU multithread';
            app.fsEditFieldLabel = uilabel(app.FileselectionPanel);
            app.fsEditFieldLabel.Tooltip = {'Sampling frequency in (kHz)'};
            app.fsEditFieldLabel.Text = 'fs';
            app.fs = uieditfield(app.FileselectionPanel, 'numeric');
            app.fs.Limits = [0 Inf];
            app.fs.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.fs.Editable = 'off';
            app.fs.Tooltip = {'Sampling frequency (kHz)'};
            app.NotesTextAreaLabel = uilabel(app.FileselectionPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.FileselectionPanel);
            app.numworkersSpinnerLabel = uilabel(app.FileselectionPanel);
            app.numworkersSpinnerLabel.Text = 'num workers';
            app.parfor_arg = uispinner(app.FileselectionPanel);
            app.parfor_arg.Limits = [-1 32];
            app.parfor_arg.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.parfor_arg.Tooltip = {'Number of worker used for the video rendering (depends of the working station)'};
            app.parfor_arg.Value = 10;
            app.Label = uilabel(app.FileselectionPanel);
            app.Label.Text = '';
            app.setUpDropDown = uidropdown(app.FileselectionPanel);
            app.setUpDropDown.Items = {'Doppler'};
            app.setUpDropDown.Value = 'Doppler';
            app.ppy = uieditfield(app.FileselectionPanel, 'numeric');
            app.ppy.Limits = [0 10];
            app.ppy.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.ppy.Tooltip = {'Number of frequencies intervals you want to have the M0 of.'};
            app.ppxy_label = uilabel(app.FileselectionPanel);
            app.ppxy_label.Tooltip = {'Camera pixel pitch (distance between spatial sampling points) in (m)'};
            app.ppxy_label.Text = 'pp x y';
            app.ppx = uieditfield(app.FileselectionPanel, 'numeric');
            app.ppx.Limits = [0 10];
            app.ppx.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.ppx.Tooltip = {'Number of frequencies intervals you want to have the M0 of.'};
            app.frame_position = uieditfield(app.FileselectionPanel, 'numeric');
            app.frame_position.Limits = [0 Inf];
            app.frame_position.RoundFractionalValues = 'on';
            app.frame_position.ValueDisplayFormat = '%.0f';
            app.frame_position.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.frame_position.Tooltip = {''};
            app.positioninfileSliderLabel = uilabel(app.FileselectionPanel);
            app.positioninfileSliderLabel.Text = {'position in file'; ''};
            app.positioninfileSlider = uislider(app.FileselectionPanel);
            app.positioninfileSlider.Limits = [0 1];
            app.positioninfileSlider.MajorTicks = [];
            app.positioninfileSlider.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.positioninfileSlider.MinorTicks = [];
            app.positioninfileSlider.Tooltip = {'Change value to display a different video timestamp in the gui.'};
            app.file_loaded_lamp = uilamp(app.FileselectionPanel);
            app.num_frames = uilabel(app.FileselectionPanel);
            app.num_frames.Text = '/';
            app.RefreshAppButton = uibutton(app.FileselectionPanel, 'push');
            app.RefreshAppButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshAppButtonPushed, true);
            app.RefreshAppButton.Tooltip = {'Refresh the app'};
            app.RefreshAppButton.Text = '👌';
            app.CurrentFilePanel = uipanel(app.GridLayout4);
            app.CurrentFilePanel.Tooltip = {''};
            app.CurrentFilePanel.Title = 'Current file';
            app.GridLayout = uigridlayout(app.CurrentFilePanel);
            app.AdvancedProcessingPanel = uipanel(app.GridLayout);
            app.AdvancedProcessingPanel.Title = 'Advanced Processing';
            app.AdvancedProcessingPanel.Visible = 'off';
            app.SVDCheckBox = uicheckbox(app.AdvancedProcessingPanel);
            app.SVDCheckBox.Tooltip = {'Enable SVD hologram filtering in hologram construction'};
            app.SVDCheckBox.Text = 'SVD';
            app.SVDCheckBox.Value = true;
            app.spatialCheckBox = uicheckbox(app.AdvancedProcessingPanel);
            app.spatialCheckBox.Text = 'spatial';
            app.temporalCheckBox = uicheckbox(app.AdvancedProcessingPanel);
            app.temporalCheckBox.Text = 'temporal';
            app.LocalfilteringLabel = uilabel(app.AdvancedProcessingPanel);
            app.LocalfilteringLabel.Text = 'Local filtering';
            app.phi1EditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.phi1EditFieldLabel.Text = 'phi1';
            app.phi1EditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.phi2EditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.phi2EditFieldLabel.Text = 'phi2';
            app.phi2EditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.nu1EditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.nu1EditFieldLabel.Text = 'nu1';
            app.nu1EditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.nu2EditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.nu2EditFieldLabel.Text = 'nu2';
            app.nu2EditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.unitcellsinlatticeEditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.unitcellsinlatticeEditFieldLabel.Text = '# unit cells in lattice';
            app.unitcellsinlatticeEditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.unitcellsinlatticeEditField.Limits = [0 Inf];
            app.unitcellsinlatticeEditField.Value = 8;
            app.r1EditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.r1EditFieldLabel.Text = 'r1';
            app.r1EditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.r1EditField.Limits = [0 Inf];
            app.r1EditField.Value = 3;
            app.xystrideEditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.xystrideEditFieldLabel.Text = 'xy stride';
            app.xystrideEditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.xystrideEditField.Value = 32;
            app.SVDx_SubApEditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.SVDx_SubApEditFieldLabel.Text = 'SVDx_SubAp';
            app.SVDx_SubApEditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.SVDx_SubApEditField.Limits = [0 20];
            app.SVDx_SubApEditField.Value = 3;
            app.SVDxCheckBox = uicheckbox(app.AdvancedProcessingPanel);
            app.SVDxCheckBox.Text = 'SVDx';
            app.SVDThresholdEditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.SVDThresholdEditField.Limits = [0 Inf];
            app.SVDThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @SVDThresholdEditFieldValueChanged, true);
            app.SVDThresholdEditField.Enable = 'off';
            app.SVDThresholdEditField.Value = 64;
            app.SVDTresholdEditFieldLabel = uilabel(app.AdvancedProcessingPanel);
            app.SVDTresholdEditFieldLabel.Text = 'SVD Threshold';
            app.SVDThresholdCheckBox = uicheckbox(app.AdvancedProcessingPanel);
            app.SVDThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @SVDThresholdCheckBoxValueChanged, true);
            app.SVDThresholdCheckBox.Text = '';
            app.SVDStrideEditField = uieditfield(app.AdvancedProcessingPanel, 'numeric');
            app.SVDStrideEditField.Limits = [0 Inf];
            app.SVDStrideEditField.Tooltip = {'Sub sampling parameter for faster SVD calculations. Defaults to 1 -> full image, 2 -> one pixel on two, ...'};
            app.SVDStrideEditField.Value = 1;
            app.SVDTresholdEditFieldLabel_2 = uilabel(app.AdvancedProcessingPanel);
            app.SVDTresholdEditFieldLabel_2.Text = 'SVD Stride';
            app.AberrationcompensationPanel = uipanel(app.GridLayout);
            app.AberrationcompensationPanel.Title = 'Aberration compensation';
            app.AberrationcompensationPanel.Visible = 'off';
            app.UIAxes_aberrationPreview = uiaxes(app.AberrationcompensationPanel);
            app.UIAxes_aberrationPreview.CLim = [0 1];
            app.UIAxes_aberrationPreview.XTick = [];
            app.UIAxes_aberrationPreview.YTick = [];
            app.Label_2 = uilabel(app.AberrationcompensationPanel);
            app.Label_2.Text = {''; ''};
            app.ShackHartmannCheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.ShackHartmannCheckBox.Tooltip = {'Perform aberration compensation with Shack Hartmann simulation'};
            app.ShackHartmannCheckBox.Text = 'Shack Hartmann';
            app.zernikeranksEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.zernikeranksEditFieldLabel.Text = 'zernike ranks';
            app.shackhartmannzernikeranksEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.shackhartmannzernikeranksEditField.Limits = [2 6];
            app.shackhartmannzernikeranksEditField.RoundFractionalValues = 'on';
            app.shackhartmannzernikeranksEditField.Tooltip = {'Number of zernike ranks to use for aberration correction'};
            app.shackhartmannzernikeranksEditField.Value = 2;
            app.subaperturemarginEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.subaperturemarginEditFieldLabel.Text = 'subaperture margin';
            app.subaperturemarginEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.subaperturemarginEditField.Limits = [0 Inf];
            app.subaperturemarginEditField.Tooltip = {'Number of subapertures used for Shack-Hartmann simulation'};
            app.subaperturemarginEditField.Value = 0.3;
            app.IterativeoptimizationCheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.IterativeoptimizationCheckBox.Tooltip = {'Activate aberration compensation via image entropy optimization. This might significantly increase the computation time.'};
            app.IterativeoptimizationCheckBox.Text = 'Iterative optimization';
            app.zernikeranksEditField_2Label = uilabel(app.AberrationcompensationPanel);
            app.zernikeranksEditField_2Label.Visible = 'off';
            app.zernikeranksEditField_2Label.Text = 'zernike ranks';
            app.optimizationzernikeranksEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.optimizationzernikeranksEditField.Limits = [2 Inf];
            app.optimizationzernikeranksEditField.Visible = 'off';
            app.optimizationzernikeranksEditField.Tooltip = {'Number of zernike ranks that constitue the aberration projection polynomial space'};
            app.optimizationzernikeranksEditField.Value = 2;
            app.toleranceLabel = uilabel(app.AberrationcompensationPanel);
            app.toleranceLabel.Visible = 'off';
            app.toleranceLabel.Text = 'tolerance';
            app.zernikestolEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.zernikestolEditField.Limits = [0 Inf];
            app.zernikestolEditField.Visible = 'off';
            app.zernikestolEditField.Tooltip = {''};
            app.zernikestolEditField.Value = 0.05;
            app.maxconstraintEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.maxconstraintEditFieldLabel.Visible = 'off';
            app.maxconstraintEditFieldLabel.Text = 'max constraint';
            app.maxconstraintEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.maxconstraintEditField.Limits = [1 Inf];
            app.maxconstraintEditField.RoundFractionalValues = 'on';
            app.maxconstraintEditField.Visible = 'off';
            app.maxconstraintEditField.Tooltip = {''};
            app.maxconstraintEditField.Value = 10;
            app.masknumiterEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.masknumiterEditFieldLabel.Visible = 'off';
            app.masknumiterEditFieldLabel.Text = 'mask num iter';
            app.masknumiterEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.masknumiterEditField.Limits = [1 Inf];
            app.masknumiterEditField.RoundFractionalValues = 'on';
            app.masknumiterEditField.Visible = 'off';
            app.masknumiterEditField.Tooltip = {''};
            app.masknumiterEditField.Value = 1;
            app.PreviewLabel = uilabel(app.AberrationcompensationPanel);
            app.PreviewLabel.Text = {'astig_1 : 0.0'; 'defocus: 0.0'; 'astig_2 : 0.0'};
            app.SubAp_PCACheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.SubAp_PCACheckBox.ValueChangedFcn = createCallbackFcn(app, @SubAp_PCACheckBoxValueChanged, true);
            app.SubAp_PCACheckBox.Text = 'SubAp_PCA';
            app.minEditField_2Label = uilabel(app.AberrationcompensationPanel);
            app.minEditField_2Label.Text = 'min';
            app.minSubAp_PCAEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.minSubAp_PCAEditField.Limits = [1 Inf];
            app.minSubAp_PCAEditField.ValueChangedFcn = createCallbackFcn(app, @minSubApPCAEditField_2ValueChanged, true);
            app.minSubAp_PCAEditField.Value = 1;
            app.maxEditField_2Label = uilabel(app.AberrationcompensationPanel);
            app.maxEditField_2Label.Text = 'max';
            app.maxSubAp_PCAEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.maxSubAp_PCAEditField.Limits = [1 Inf];
            app.maxSubAp_PCAEditField.ValueChangedFcn = createCallbackFcn(app, @maxSubAp_PCAEditField_2_2ValueChanged, true);
            app.maxSubAp_PCAEditField.Value = 16;
            app.volumesizeEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.volumesizeEditFieldLabel.Visible = 'off';
            app.volumesizeEditFieldLabel.Text = 'volume size';
            app.volumesizeEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.volumesizeEditField.Limits = [1 Inf];
            app.volumesizeEditField.Visible = 'off';
            app.volumesizeEditField.Value = 256;
            app.rangeZEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.rangeZEditFieldLabel.Visible = 'off';
            app.rangeZEditFieldLabel.Text = 'rangeZ';
            app.rangeZEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.rangeZEditField.Limits = [1 Inf];
            app.rangeZEditField.ValueChangedFcn = createCallbackFcn(app, @rangeZEditFieldValueChanged, true);
            app.rangeZEditField.Visible = 'off';
            app.rangeZEditField.Value = 1;
            app.rangeYEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.rangeYEditFieldLabel.Visible = 'off';
            app.rangeYEditFieldLabel.Text = 'rangeY';
            app.rangeYEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.rangeYEditField.Limits = [1 Inf];
            app.rangeYEditField.ValueChangedFcn = createCallbackFcn(app, @rangeYEditFieldValueChanged, true);
            app.rangeYEditField.Visible = 'off';
            app.rangeYEditField.Value = 1;
            app.savecoefsCheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.savecoefsCheckBox.Text = 'save coefs';
            app.ZernikeProjectionCheckBox = uicheckbox(app.AberrationcompensationPanel);
            app.ZernikeProjectionCheckBox.Tooltip = {'Perform aberration compensation with Shack Hartmann simulation'};
            app.ZernikeProjectionCheckBox.Text = 'Zernike Projection';
            app.referenceimageDropDownLabel = uilabel(app.AberrationcompensationPanel);
            app.referenceimageDropDownLabel.Text = 'reference image';
            app.referenceimageDropDown = uidropdown(app.AberrationcompensationPanel);
            app.referenceimageDropDown.Items = {'central subaperture', 'resized image'};
            app.referenceimageDropDown.Value = 'central subaperture';
            app.subapnumpositionsEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.subapnumpositionsEditFieldLabel.Text = 'subap num positions';
            app.subapnumpositionsEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.subapnumpositionsEditField.Limits = [1 20];
            app.subapnumpositionsEditField.RoundFractionalValues = 'on';
            app.subapnumpositionsEditField.Tooltip = {'Number of subapertures used for Shack-Hartmann simulation'};
            app.subapnumpositionsEditField.Value = 5;
            app.imagesubapsizeratioEditFieldLabel = uilabel(app.AberrationcompensationPanel);
            app.imagesubapsizeratioEditFieldLabel.Text = 'image subap size ratio';
            app.imagesubapsizeratioEditField = uieditfield(app.AberrationcompensationPanel, 'numeric');
            app.imagesubapsizeratioEditField.Limits = [1 20];
            app.imagesubapsizeratioEditField.RoundFractionalValues = 'on';
            app.imagesubapsizeratioEditField.Tooltip = {'Number of subapertures used for Shack-Hartmann simulation'};
            app.imagesubapsizeratioEditField.Value = 5;
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.RenderingparametersPanel = uipanel(app.GridLayout3);
            app.RenderingparametersPanel.Title = 'Rendering parameters';
            app.svd_threshold_reset_button = uibutton(app.RenderingparametersPanel, 'push');
            app.svd_threshold_reset_button.ButtonPushedFcn = createCallbackFcn(app, @svd_threshold_reset_buttonPushed, true);
            app.svd_threshold_reset_button.Text = 'svd_threshold';
            app.spatial_filter = uicheckbox(app.RenderingparametersPanel);
            app.spatial_filter.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.spatial_filter.Tooltip = {'Filter the spatial frequencies of the interferograms keeping only those between spatial filter range1 and 2 (between 0 and 1-> highest dimension)'};
            app.spatial_filter.Text = 'spatial_filter';
            app.hilbert_filter = uicheckbox(app.RenderingparametersPanel);
            app.hilbert_filter.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.hilbert_filter.Tooltip = {'Apply a hilbert transformation on the interferogram batch to get an analytical signal of each pixel.'};
            app.hilbert_filter.Text = 'hilbert_filter';
            app.spatial_filter_range = uilabel(app.RenderingparametersPanel);
            app.spatial_filter_range.Text = 'spatial_filter_range';
            app.spatial_filter_range1 = uieditfield(app.RenderingparametersPanel, 'numeric');
            app.spatial_filter_range1.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.spatial_filter_range2 = uieditfield(app.RenderingparametersPanel, 'numeric');
            app.spatial_filter_range2.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.spatial_transformationDropDownLabel = uilabel(app.RenderingparametersPanel);
            app.spatial_transformationDropDownLabel.Text = 'spatial_transformation';
            app.spatial_transformation = uidropdown(app.RenderingparametersPanel);
            app.spatial_transformation.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.spatial_transformation.Tooltip = {'Type of light propagation calculation to perform (depends of the experimental setup)'};
            app.spatial_propagationEditFieldLabel = uilabel(app.RenderingparametersPanel);
            app.spatial_propagationEditFieldLabel.Text = 'spatial_propagation';
            app.spatial_propagation = uieditfield(app.RenderingparametersPanel, 'numeric');
            app.spatial_propagation.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.spatial_propagation.Tooltip = {'Distance of spatial reconstruction using the preceding calculation scheme in (m) '};
            app.svd_filter = uicheckbox(app.RenderingparametersPanel);
            app.svd_filter.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.svd_filter.Tooltip = {'Filter to remove intense time correlated feature images of the output fluctuation hologram using eigenvalue decomposition of the correlation matrix of frames.'};
            app.svd_filter.Text = 'svd_filter';
            app.svdx_filter = uicheckbox(app.RenderingparametersPanel);
            app.svdx_filter.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.svdx_filter.Tooltip = {'Same filter but decomposition bloc by bloc of frames'};
            app.svdx_filter.Text = 'svdx_filter';
            app.svd_threshold = uieditfield(app.RenderingparametersPanel, 'numeric');
            app.svd_threshold.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.svd_threshold.Tooltip = {'number of first eigenvectors to remove from the fluctuation holograms (zero means default => time_range(1)/fs * batch_size * 2)'};
            app.time_transformDropDownLabel = uilabel(app.RenderingparametersPanel);
            app.time_transformDropDownLabel.Text = 'time_transform';
            app.time_transform = uidropdown(app.RenderingparametersPanel);
            app.time_transform.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.time_transform.Tooltip = {'Time tranformation to aggregate the fluctuation hologram. FFT is a frequency domain tranform and pass bad filter, PCA is a projection on intensity ordered eigen vectors, ICA is experimental.'};
            app.time_rangeLabel = uilabel(app.RenderingparametersPanel);
            app.time_rangeLabel.Text = 'time_range';
            app.time_range1 = uieditfield(app.RenderingparametersPanel, 'numeric');
            app.time_range1.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.time_range2 = uieditfield(app.RenderingparametersPanel, 'numeric');
            app.time_range2.ValueChangedFcn = createCallbackFcn(app, @time_range2ValueChanged, true);
            app.flat_field_gwEditFieldLabel = uilabel(app.RenderingparametersPanel);
            app.flat_field_gwEditFieldLabel.Text = 'flat_field_gw';
            app.flat_field_gw = uieditfield(app.RenderingparametersPanel, 'numeric');
            app.flat_field_gw.ValueChangedFcn = createCallbackFcn(app, @refreshClass, true);
            app.flat_field_gw.Tooltip = {'flat_filed parameter to apply to some of the output images (gaussian width in pixels to divide the image to correct uneven illumination of images).'};
            app.RenderPreviewButton = uibutton(app.RenderingparametersPanel, 'push');
            app.RenderPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @RenderPreviewButtonPushed, true);
            app.RenderPreviewButton.Text = 'Render Preview';
            app.SavePreviewButton = uibutton(app.RenderingparametersPanel, 'push');
            app.SavePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @SavePreviewButtonPushed, true);
            app.SavePreviewButton.Text = 'Save Preview';
            app.RenderPreviewLamp = uilamp(app.RenderingparametersPanel);
            app.ImageLeft = uiimage(app.GridLayout4);
            app.ImageLeft.ImageClickedFcn = createCallbackFcn(app, @ImageLeftClicked, true);
            app.ImageRight = uiimage(app.GridLayout4);
            app.ImageRight.ImageClickedFcn = createCallbackFcn(app, @ImageRightClicked, true);
            app.RightClickImageContextMenu = uicontextmenu(app.HoloDopplerUIFigure);
            app.NextMenu = uimenu(app.RightClickImageContextMenu);
            app.NextMenu.MenuSelectedFcn = createCallbackFcn(app, @NextMenuSelected, true);
            app.NextMenu.Text = 'Next';
            app.ViewAllMenu = uimenu(app.RightClickImageContextMenu);
            app.ViewAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewAllMenuSelected, true);
            app.ViewAllMenu.Text = 'ViewAll';
            % Assign app.RightClickImageContextMenu
            app.ImageLeft.ContextMenu = app.RightClickImageContextMenu;
            % Show the figure after all components are created
            app.HoloDopplerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = holodoppler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HoloDopplerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HoloDopplerUIFigure)
        end
    end
end